classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalculatePotentialTab           matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        SigmaEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel             matlab.ui.control.Label
        WellDepthEditField              matlab.ui.control.NumericEditField
        WellDepthEditFieldLabel         matlab.ui.control.Label
        SpecifyLJParametersLabel        matlab.ui.control.Label
        minrEditField                   matlab.ui.control.NumericEditField
        minrEditFieldLabel              matlab.ui.control.Label
        maxrEditField                   matlab.ui.control.NumericEditField
        maxrEditFieldLabel              matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Z2EditField                     matlab.ui.control.NumericEditField
        Z2EditFieldLabel                matlab.ui.control.Label
        Z1EditField                     matlab.ui.control.NumericEditField
        Z1EditFieldLabel                matlab.ui.control.Label
        SpecifyCoulombParametersLabel   matlab.ui.control.Label
        PotentialTypeDropDown           matlab.ui.control.DropDown
        PotentialTypeDropDownLabel      matlab.ui.control.Label
        SelectedPathEditField_4         matlab.ui.control.EditField
        SelectedPathEditField_4Label    matlab.ui.control.Label
        SelectInputFileButton           matlab.ui.control.Button
        OrSelectInputFileLabel          matlab.ui.control.Label
        SpecifyWheretoSavePotentialDataLabel  matlab.ui.control.Label
        SelectedPathEditField_3         matlab.ui.control.EditField
        SelectedPathEditField_3Label    matlab.ui.control.Label
        SelectPotentialPathButton       matlab.ui.control.Button
        SpecifyWheretoSaveInputFileLabel  matlab.ui.control.Label
        SelectedPathEditField_2         matlab.ui.control.EditField
        SelectedPathEditField_2Label    matlab.ui.control.Label
        SelectInputPathButton           matlab.ui.control.Button
        ExecuteButton                   matlab.ui.control.Button
        WriteInputFileButton            matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        LINEARTab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        LOGTab                          matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        FitPotentialTab                 matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        sigmaguessEditField             matlab.ui.control.NumericEditField
        sigmaguessEditFieldLabel        matlab.ui.control.Label
        welldepthguessEditField         matlab.ui.control.NumericEditField
        welldepthguessEditFieldLabel    matlab.ui.control.Label
        maxsigmaEditField               matlab.ui.control.NumericEditField
        maxsigmaEditFieldLabel          matlab.ui.control.Label
        minsigmaEditField               matlab.ui.control.NumericEditField
        minsigmaEditFieldLabel          matlab.ui.control.Label
        maxwelldepthEditField           matlab.ui.control.NumericEditField
        maxwelldepthEditFieldLabel      matlab.ui.control.Label
        minwelldepthEditField           matlab.ui.control.NumericEditField
        minwelldepthEditFieldLabel      matlab.ui.control.Label
        SpecifyLJParametersLabel_2      matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        minZ2EditField                  matlab.ui.control.NumericEditField
        minZ2EditFieldLabel             matlab.ui.control.Label
        maxZ2EditField                  matlab.ui.control.NumericEditField
        maxZ2EditFieldLabel             matlab.ui.control.Label
        Z1EditField_2                   matlab.ui.control.NumericEditField
        Z1EditField_2Label              matlab.ui.control.Label
        SpecifyCoulombParametersLabel_2  matlab.ui.control.Label
        maxrEditField_2                 matlab.ui.control.NumericEditField
        maxrEditField_2Label            matlab.ui.control.Label
        minrEditField_2                 matlab.ui.control.NumericEditField
        minrEditField_2Label            matlab.ui.control.Label
        toleranceEditField              matlab.ui.control.NumericEditField
        toleranceEditFieldLabel         matlab.ui.control.Label
        TabGroup2_2                     matlab.ui.container.TabGroup
        LINEARTab_2                     matlab.ui.container.Tab
        UIAxes_3                        matlab.ui.control.UIAxes
        LOGTab_2                        matlab.ui.container.Tab
        UIAxes_4                        matlab.ui.control.UIAxes
        FitTypeDropDown                 matlab.ui.control.DropDown
        FitTypeDropDownLabel            matlab.ui.control.Label
        OrSelectYourOwnPotentialDataFileLabel  matlab.ui.control.Label
        DataLocationEditField           matlab.ui.control.EditField
        DataLocationEditFieldLabel      matlab.ui.control.Label
        SelectedPathEditField           matlab.ui.control.EditField
        SelectedPathEditFieldLabel      matlab.ui.control.Label
        SelectPotentialFileButton       matlab.ui.control.Button
        ExecuteButton_2                 matlab.ui.control.Button
        CalculateScatteringIntegralsTab  matlab.ui.container.Tab
        SelectPathButton_2              matlab.ui.control.Button
        SelectedPathEditField_6         matlab.ui.control.EditField
        SelectedPathEditField_6Label    matlab.ui.control.Label
        SpecifyWheretoSaveInputFileLabel_2  matlab.ui.control.Label
        SelectedFileEditField           matlab.ui.control.EditField
        SelectedFileEditFieldLabel      matlab.ui.control.Label
        SelectScatteringInputFileButton  matlab.ui.control.Button
        OrselectyourownscatteringinputfileLabel  matlab.ui.control.Label
        WriteScatteringInputFileButton  matlab.ui.control.Button
        SelectedPathEditField_5         matlab.ui.control.EditField
        SelectedPathEditField_5Label    matlab.ui.control.Label
        SpecifyWheretoSaveDifferentialScatteringDataLabel  matlab.ui.control.Label
        SelectPathButton                matlab.ui.control.Button
        TabGroup2_3                     matlab.ui.container.TabGroup
        DifferentialScatteringCrossSectionTab  matlab.ui.container.Tab
        UIAxes_5                        matlab.ui.control.UIAxes
        ImpactParameterTab              matlab.ui.container.Tab
        UIAxes_6                        matlab.ui.control.UIAxes
        DistofClosestApproachTab        matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        ExecuteButton_3                 matlab.ui.control.Button
        Panel_7                         matlab.ui.container.Panel
        ScatteringIntegralsLabel        matlab.ui.control.Label
        thetamaxradEditField            matlab.ui.control.NumericEditField
        thetamaxradEditFieldLabel       matlab.ui.control.Label
        thetaminradEditField            matlab.ui.control.NumericEditField
        thetaminradEditFieldLabel       matlab.ui.control.Label
        z2EditField                     matlab.ui.control.NumericEditField
        z2EditFieldLabel                matlab.ui.control.Label
        z1EditField                     matlab.ui.control.NumericEditField
        z1EditFieldLabel                matlab.ui.control.Label
        EeVEditField                    matlab.ui.control.NumericEditField
        EeVEditFieldLabel               matlab.ui.control.Label
        CalcuateTransportCoefficientsTab  matlab.ui.container.Tab
        CalcuateDSMCCoefficientsTab     matlab.ui.container.Tab
    end

    %these are properties that I utilize across different callbacks
    properties (Access = public)
        matfile % Description
        directory
        DataSetName
        usrnumber
        selpath
        selpath2
        selpath3
        selpath4
        selpath5
        selpath6
        selpath7
        selpath8
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.FontName="Courier";
            app.TabGroup.FontWeight = 'bold';
            app.TabGroup2.FontName="Courier";
            app.TabGroup2.FontWeight = 'bold';
            app.Panel_3.Visible='off';
            app.Panel_4.Visible='off';
            app.Panel.Visible='off';
            app.Panel_2.Visible='off';
        end

        % Button pushed function: ExecuteButton_2
        function ExecuteButton_2Pushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            % and executes run_calcpotential, plots data, pushes data
            %location to next tab
            z1 = app.Z1EditField_2.Value;
            minr = app.minrEditField_2.Value;
            maxr = app.maxrEditField_2.Value;
            tol = app.toleranceEditField.Value;
            minz2 = app.minZ2EditField.Value;
            maxz2 = app.maxZ2EditField.Value;
            min_eps = app.minwelldepthEditField.Value;
            max_eps = app.maxwelldepthEditField.Value;
            min_sigma = app.minsigmaEditField.Value;
            max_sigma = app.maxsigmaEditField.Value;
            eps_start = app.welldepthguessEditField.Value;
            sigma_start = app.sigmaguessEditField.Value;
            filename  = [app.selpath '/fitinputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile,'Potential_Type = "%s";\n',app.FitTypeDropDown.Value);
            fprintf(inputfile, 'minR = %f;\n',minr);
            fprintf(inputfile, 'maxR = %f;\n',maxr);
            fprintf(inputfile,'tol = %e;\n',tol);
            if app.FitTypeDropDown.Value == "Coulomb"
                fprintf(inputfile, 'Z1 = %i;\n',z1);
                fprintf(inputfile, 'minZ2 = %i;\n',minz2);
                fprintf(inputfile, 'maxZ2 = %i;\n',maxz2);
            elseif app.FitTypeDropDown.Value == "Lennard-Jones"
                fprintf(inputfile, 'min_eps = %f;\n',min_eps);
                fprintf(inputfile, 'max_eps = %f;\n',max_eps);
                fprintf(inputfile, 'min_sigma = %f;\n',min_sigma);
                fprintf(inputfile, 'max_sigma = %f;\n',max_sigma);
                fprintf(inputfile, 'eps_start = %f;\n',eps_start);
                fprintf(inputfile, 'sigma_start = %f;\n',sigma_start);
            end
            fclose(inputfile);
            addpath('./src')
            addpath('../src')
            datalocation = [app.selpath '/data.csv'];
            t = readmatrix(datalocation);
            myfit = run_fitpotential(app.selpath);
            outputfilename  = [app.selpath '/fitoutputfile.m'];
            outputfile=fopen(outputfilename,'w');
            fprintf(outputfile,'Potential_Type = "%s";\n',app.FitTypeDropDown.Value);
            fprintf(outputfile,'Params = %s;\n',coeffvalues(myfit));
            fprintf(outputfile, 'minR = %f;\n',minr);
            fprintf(outputfile, 'maxR = %f;\n',maxr);
            fprintf(outputfile, 'Tol = %e;\n',tol);
            if app.FitTypeDropDown.Value == "Coulomb"
                fprintf(outputfile, 'minZ2 = %i;\n',minz2);
                fprintf(outputfile, 'maxZ2 = %i;\n',maxz2);
            elseif app.FitTypeDropDown.Value == "Lennard-Jones"
                fprintf(outputfile, 'min_eps = %f;\n',min_eps);
                fprintf(outputfile, 'max_eps = %f;\n',max_eps);
                fprintf(outputfile, 'min_sigma = %f;\n',min_sigma);
                fprintf(outputfile, 'max_sigma = %f;\n',max_sigma);
                fprintf(outputfile, 'eps_start = %f;\n',eps_start);
                fprintf(outputfile, 'sigma_start = %f;\n',sigma_start);
            end
            fclose(outputfile);
            excludex = ((t(:,1) < minr) | (t(:,1) > maxr));
            xvals = t(:,1);
            yvals = t(:,2);
            xvals(excludex) = [];
            yvals(excludex) = [];
            plot(app.UIAxes_3,myfit,xvals,yvals);
            % plot(app.UIAxes_4,myfit,xvals,yvals);
            % yscale(app.UIAxes_4,'log');
            % xscale(app.UIAxes_4,'linear');

        end

        % Button pushed function: SelectPotentialFileButton
        function SelectPotentialFileButtonPushed(app, event)
            %allows you to select input file from directory
            app.selpath  = uigetdir;
            app.SelectedPathEditField.Value = app.selpath;
            app.DataLocationEditField.Value = app.selpath;
        end

        % Clicked callback: PotentialTypeDropDown
        function PotentialTypeDropDownClicked(app, event)
            if strcmp(app.PotentialTypeDropDown.Value,'Coulomb')
                app.Panel.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_2.Visible='off';
            elseif strcmp(app.PotentialTypeDropDown.Value,'Lennard-Jones')
                app.Panel_2.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel.Visible='off';
            else 
                app.Panel.Visible='off';
                app.Panel_2.Visible='off';
            end
        end

        % Clicked callback: FitTypeDropDown
        function FitTypeDropDownClicked(app, event)
            if strcmp(app.FitTypeDropDown.Value,'Coulomb')
                app.Panel_3.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_4.Visible='off';
            elseif strcmp(app.FitTypeDropDown.Value,'Lennard-Jones')
                app.Panel_4.Visible='on'; % Set the visibility of the panel to 'on'
                app.Panel_3.Visible='off';
            else 
                app.Panel_3.Visible='off';
                app.Panel_4.Visible='off';
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            %executes run_calcpotential, plots data, pushes data
            %location to next tab
            addpath('../src')
            addpath('./src')
            run_calcpotential(app.selpath3);
            datalocation = [app.selpath3 '/data.csv'];
            t = readmatrix(datalocation);
            xvals = t(:,1);
            yvals = t(:,2);
            plot(app.UIAxes,xvals,yvals,'-o',MarkerFaceColor='b');
            plot(app.UIAxes_2,xvals,yvals,'-o',MarkerFaceColor='b');
            yscale(app.UIAxes_2,'log');
            xscale(app.UIAxes_2,'linear');
            app.DataLocationEditField.Value = app.selpath3;
        end

        % Button pushed function: WriteInputFileButton
        function WriteInputFileButtonPushed2(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            minr = app.minrEditField.Value;
            maxr = app.maxrEditField.Value;
            z1 = app.Z1EditField.Value;
            z2 = app.Z2EditField.Value;
            well_eps = app.WellDepthEditField.Value;
            sigma = app.SigmaEditField.Value;
            filename  = [app.selpath5 '/inputfile.m'];
            inputfile=fopen(filename,'w');
            fprintf(inputfile,'Potential_Type = "%s";\n',app.PotentialTypeDropDown.Value);
            fprintf(inputfile, 'minR = %f;\n',minr);
            fprintf(inputfile, 'maxR = %f;\n',maxr);
            if app.PotentialTypeDropDown.Value == "Coulomb"
                fprintf(inputfile, 'Z1 = %i;\n',z1);
                fprintf(inputfile, 'Z2 = %i;\n',z2);
            elseif app.PotentialTypeDropDown.Value == "Lennard-Jones"
                fprintf(inputfile, 'eps_well = %f;\n',well_eps);
                fprintf(inputfile, 'sigma = %i;\n',sigma);
            end
            fclose(inputfile);
            app.SelectedPathEditField_4.Value = filename;
        end

        % Button pushed function: SelectPotentialPathButton
        function SelectPotentialPathButtonPushed2(app, event)
            app.selpath3  = uigetdir;
            app.SelectedPathEditField_3.Value = app.selpath3;
        end

        % Button pushed function: SelectInputFileButton
        function SelectInputFileButtonPushed2(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.selpath4 = fullfile(folder, baseName);
            app.SelectedPathEditField_4.Value = app.selpath4;
        end

        % Button pushed function: SelectInputPathButton
        function SelectInputPathButtonPushed(app, event)
            app.selpath5  = uigetdir;
            app.SelectedPathEditField_2.Value = app.selpath5;
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            app.selpath6  = uigetdir;
            app.SelectedPathEditField_5.Value = app.selpath6;
        end

        % Button pushed function: ExecuteButton_3
        function ExecuteButton_3Pushed(app, event)
           
            addpath('./src')
            addpath('../src')
            run_scatteringintegrals(app.selpath6);
            difscatterdatalocation = [app.selpath6 '/difscatterdata.csv'];
            impactdatalocation = [app.selpath6 '/impactparamdata.csv'];
            docadatalocation = [app.selpath6 '/docadata.csv'];
            tdif = readmatrix(difscatterdatalocation);
            timpact = readmatrix(impactdatalocation);
            tdoca = readmatrix(docadatalocation);
            xvals1 = tdif(:,1);
            yvals1 = tdif(:,2);
            xvals2 = timpact(:,1);
            yvals2 = timpact(:,2);
            xvals3 = tdoca(:,1);
            yvals3 = tdoca(:,2);
            plot(app.UIAxes_5,xvals1,yvals1,'-o',MarkerFaceColor='b');
            plot(app.UIAxes_6,xvals2,yvals2,'-o',MarkerFaceColor='b');
            plot(app.UIAxes_7,xvals3,yvals3,'-o',MarkerFaceColor='b');
            yscale(app.UIAxes_7,'log');
            xscale(app.UIAxes_7,'log');
         
     
        end

        % Button pushed function: SelectScatteringInputFileButton
        function SelectScatteringInputFileButtonPushed(app, event)
            %allows user to select their own input file
            [baseName, folder] = uigetfile();
            app.selpath7 = fullfile(folder, baseName);
            app.SelectedFileEditField.Value = app.selpath7;
        end

        % Button pushed function: WriteScatteringInputFileButton
        function WriteScatteringInputFileButtonPushed(app, event)
            %creates input file with user-specified parameters
            %saves input file in user specified directory
            % and executes run_calcpotential, plots data, pushes data
            %location to next tab
            E = app.EeVEditField.Value;
            z1 = app.z1EditField.Value;
            z2 = app.z2EditField.Value;
            theta_min = app.thetaminradEditField.Value;
            theta_max = app.thetamaxradEditField.Value;
            filename  = [app.selpath8 '/scatterintinputfile.m'];
            inputfile=fopen(filename,'w');
            %fprintf(inputfile,'Potential_Type = "%s";\n',app.FitTypeDropDown.Value);
            fprintf(inputfile, 'E = %f;\n',E);
            fprintf(inputfile, 'Z1 = %i;\n',z1);
            fprintf(inputfile,'Z2 = %i;\n',z2);
            fprintf(inputfile,'theta_min = %f; \n',theta_min);
            fprintf(inputfile,'theta_max = %f; \n',theta_max);
            fclose(inputfile);
            app.SelectedFileEditField.Value = filename;
        end

        % Button pushed function: SelectPathButton_2
        function SelectPathButton_2Pushed(app, event)
            app.selpath8  = uigetdir;
            app.SelectedPathEditField_6.Value = app.selpath8;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculatePotentialTab = uitab(app.TabGroup);
            app.CalculatePotentialTab.Title = 'Calculate Potential';
            app.TabGroup2 = uitabgroup(app.CalculatePotentialTab);
            app.LINEARTab = uitab(app.TabGroup2);
            app.LINEARTab.Title = 'LINEAR';
            app.UIAxes = uiaxes(app.LINEARTab);
            title(app.UIAxes, 'Interatomic Potential')
            xlabel(app.UIAxes, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes, 'Energy (eV)')
            zlabel(app.UIAxes, 'Z')
            app.LOGTab = uitab(app.TabGroup2);
            app.LOGTab.Title = 'LOG';
            app.UIAxes_2 = uiaxes(app.LOGTab);
            title(app.UIAxes_2, 'Interatomic Potential')
            xlabel(app.UIAxes_2, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_2, 'Energy (eV)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XScale = 'log';
            app.UIAxes_2.YScale = 'log';
            app.WriteInputFileButton = uibutton(app.CalculatePotentialTab, 'push');
            app.WriteInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteInputFileButtonPushed2, true);
            app.WriteInputFileButton.Text = 'Write Input File';
            app.ExecuteButton = uibutton(app.CalculatePotentialTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SelectInputPathButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectInputPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputPathButtonPushed, true);
            app.SelectInputPathButton.Text = 'Select Input Path';
            app.SelectedPathEditField_2Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_2Label.Text = 'Selected Path';
            app.SelectedPathEditField_2 = uieditfield(app.CalculatePotentialTab, 'text');
            app.SpecifyWheretoSaveInputFileLabel = uilabel(app.CalculatePotentialTab);
            app.SpecifyWheretoSaveInputFileLabel.Text = 'Specify Where to Save Input File';
            app.SelectPotentialPathButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectPotentialPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPotentialPathButtonPushed2, true);
            app.SelectPotentialPathButton.Text = 'Select Potential Path';
            app.SelectedPathEditField_3Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_3Label.Text = 'Selected Path';
            app.SelectedPathEditField_3 = uieditfield(app.CalculatePotentialTab, 'text');
            app.SpecifyWheretoSavePotentialDataLabel = uilabel(app.CalculatePotentialTab);
            app.SpecifyWheretoSavePotentialDataLabel.Text = 'Specify Where to Save Potential Data';
            app.OrSelectInputFileLabel = uilabel(app.CalculatePotentialTab);
            app.OrSelectInputFileLabel.Text = 'Or Select Input File';
            app.SelectInputFileButton = uibutton(app.CalculatePotentialTab, 'push');
            app.SelectInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFileButtonPushed2, true);
            app.SelectInputFileButton.Text = 'Select Input File';
            app.SelectedPathEditField_4Label = uilabel(app.CalculatePotentialTab);
            app.SelectedPathEditField_4Label.Text = 'Selected Path';
            app.SelectedPathEditField_4 = uieditfield(app.CalculatePotentialTab, 'text');
            app.PotentialTypeDropDownLabel = uilabel(app.CalculatePotentialTab);
            app.PotentialTypeDropDownLabel.Text = 'Potential Type';
            app.PotentialTypeDropDown = uidropdown(app.CalculatePotentialTab);
            app.PotentialTypeDropDown.Items = {'Select Potential', 'Coulomb', 'Lennard-Jones'};
            app.PotentialTypeDropDown.ClickedFcn = createCallbackFcn(app, @PotentialTypeDropDownClicked, true);
            app.PotentialTypeDropDown.Value = 'Select Potential';
            app.Panel_6 = uipanel(app.CalculatePotentialTab);
            app.Panel = uipanel(app.Panel_6);
            app.SpecifyCoulombParametersLabel = uilabel(app.Panel);
            app.SpecifyCoulombParametersLabel.Text = 'Specify Coulomb Parameters';
            app.Z1EditFieldLabel = uilabel(app.Panel);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.Panel, 'numeric');
            app.Z2EditFieldLabel = uilabel(app.Panel);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.Panel, 'numeric');
            app.maxrEditFieldLabel = uilabel(app.Panel_6);
            app.maxrEditFieldLabel.Text = 'max r';
            app.maxrEditField = uieditfield(app.Panel_6, 'numeric');
            app.minrEditFieldLabel = uilabel(app.Panel_6);
            app.minrEditFieldLabel.Text = 'min r ';
            app.minrEditField = uieditfield(app.Panel_6, 'numeric');
            app.Panel_2 = uipanel(app.Panel_6);
            app.SpecifyLJParametersLabel = uilabel(app.Panel_2);
            app.SpecifyLJParametersLabel.Text = 'Specify LJ Parameters';
            app.WellDepthEditFieldLabel = uilabel(app.Panel_2);
            app.WellDepthEditFieldLabel.Text = 'Well Depth';
            app.WellDepthEditField = uieditfield(app.Panel_2, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.Panel_2);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.Panel_2, 'numeric');
            app.FitPotentialTab = uitab(app.TabGroup);
            app.FitPotentialTab.Title = 'Fit Potential';
            app.ExecuteButton_2 = uibutton(app.FitPotentialTab, 'push');
            app.ExecuteButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_2Pushed, true);
            app.ExecuteButton_2.Text = 'Execute';
            app.SelectPotentialFileButton = uibutton(app.FitPotentialTab, 'push');
            app.SelectPotentialFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPotentialFileButtonPushed, true);
            app.SelectPotentialFileButton.Text = 'Select Potential File';
            app.SelectedPathEditFieldLabel = uilabel(app.FitPotentialTab);
            app.SelectedPathEditFieldLabel.Text = 'Selected Path';
            app.SelectedPathEditField = uieditfield(app.FitPotentialTab, 'text');
            app.DataLocationEditFieldLabel = uilabel(app.FitPotentialTab);
            app.DataLocationEditFieldLabel.Text = 'Data Location';
            app.DataLocationEditField = uieditfield(app.FitPotentialTab, 'text');
            app.OrSelectYourOwnPotentialDataFileLabel = uilabel(app.FitPotentialTab);
            app.OrSelectYourOwnPotentialDataFileLabel.Text = 'Or Select Your Own Potential Data File';
            app.FitTypeDropDownLabel = uilabel(app.FitPotentialTab);
            app.FitTypeDropDownLabel.Text = 'Fit Type';
            app.FitTypeDropDown = uidropdown(app.FitPotentialTab);
            app.FitTypeDropDown.Items = {'Select Fit Type', 'Coulomb', 'Lennard-Jones'};
            app.FitTypeDropDown.ClickedFcn = createCallbackFcn(app, @FitTypeDropDownClicked, true);
            app.FitTypeDropDown.Value = 'Coulomb';
            app.TabGroup2_2 = uitabgroup(app.FitPotentialTab);
            app.LINEARTab_2 = uitab(app.TabGroup2_2);
            app.LINEARTab_2.Title = 'LINEAR';
            app.UIAxes_3 = uiaxes(app.LINEARTab_2);
            title(app.UIAxes_3, 'Fitted Interatomic Potential')
            xlabel(app.UIAxes_3, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_3, 'Energy (eV)')
            zlabel(app.UIAxes_3, 'Z')
            app.LOGTab_2 = uitab(app.TabGroup2_2);
            app.LOGTab_2.Title = 'LOG';
            app.UIAxes_4 = uiaxes(app.LOGTab_2);
            title(app.UIAxes_4, 'Fitted Interatomic Potential')
            xlabel(app.UIAxes_4, 'Interatomic Distance (Å)')
            ylabel(app.UIAxes_4, 'Energy (eV)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XScale = 'log';
            app.UIAxes_4.YScale = 'log';
            app.Panel_5 = uipanel(app.FitPotentialTab);
            app.toleranceEditFieldLabel = uilabel(app.Panel_5);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.toleranceEditField = uieditfield(app.Panel_5, 'numeric');
            app.minrEditField_2Label = uilabel(app.Panel_5);
            app.minrEditField_2Label.Text = 'min r ';
            app.minrEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.maxrEditField_2Label = uilabel(app.Panel_5);
            app.maxrEditField_2Label.Text = 'max r';
            app.maxrEditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.Panel_3 = uipanel(app.Panel_5);
            app.SpecifyCoulombParametersLabel_2 = uilabel(app.Panel_3);
            app.SpecifyCoulombParametersLabel_2.Text = 'Specify Coulomb Parameters';
            app.Z1EditField_2Label = uilabel(app.Panel_3);
            app.Z1EditField_2Label.Text = 'Z1';
            app.Z1EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.maxZ2EditFieldLabel = uilabel(app.Panel_3);
            app.maxZ2EditFieldLabel.Text = 'max Z2';
            app.maxZ2EditField = uieditfield(app.Panel_3, 'numeric');
            app.minZ2EditFieldLabel = uilabel(app.Panel_3);
            app.minZ2EditFieldLabel.Text = 'min Z2';
            app.minZ2EditField = uieditfield(app.Panel_3, 'numeric');
            app.Panel_4 = uipanel(app.Panel_5);
            app.SpecifyLJParametersLabel_2 = uilabel(app.Panel_4);
            app.SpecifyLJParametersLabel_2.Text = 'Specify LJ Parameters';
            app.minwelldepthEditFieldLabel = uilabel(app.Panel_4);
            app.minwelldepthEditFieldLabel.Text = 'min well depth';
            app.minwelldepthEditField = uieditfield(app.Panel_4, 'numeric');
            app.maxwelldepthEditFieldLabel = uilabel(app.Panel_4);
            app.maxwelldepthEditFieldLabel.Text = 'max well depth';
            app.maxwelldepthEditField = uieditfield(app.Panel_4, 'numeric');
            app.minsigmaEditFieldLabel = uilabel(app.Panel_4);
            app.minsigmaEditFieldLabel.Text = 'min sigma';
            app.minsigmaEditField = uieditfield(app.Panel_4, 'numeric');
            app.maxsigmaEditFieldLabel = uilabel(app.Panel_4);
            app.maxsigmaEditFieldLabel.Text = 'max sigma';
            app.maxsigmaEditField = uieditfield(app.Panel_4, 'numeric');
            app.welldepthguessEditFieldLabel = uilabel(app.Panel_4);
            app.welldepthguessEditFieldLabel.Text = 'well depth guess';
            app.welldepthguessEditField = uieditfield(app.Panel_4, 'numeric');
            app.sigmaguessEditFieldLabel = uilabel(app.Panel_4);
            app.sigmaguessEditFieldLabel.Text = 'sigma guess';
            app.sigmaguessEditField = uieditfield(app.Panel_4, 'numeric');
            app.CalculateScatteringIntegralsTab = uitab(app.TabGroup);
            app.CalculateScatteringIntegralsTab.Title = 'Calculate Scattering Integrals';
            app.Panel_7 = uipanel(app.CalculateScatteringIntegralsTab);
            app.EeVEditFieldLabel = uilabel(app.Panel_7);
            app.EeVEditFieldLabel.Text = 'E (eV)';
            app.EeVEditField = uieditfield(app.Panel_7, 'numeric');
            app.z1EditFieldLabel = uilabel(app.Panel_7);
            app.z1EditFieldLabel.Text = 'z1';
            app.z1EditField = uieditfield(app.Panel_7, 'numeric');
            app.z2EditFieldLabel = uilabel(app.Panel_7);
            app.z2EditFieldLabel.Text = 'z2';
            app.z2EditField = uieditfield(app.Panel_7, 'numeric');
            app.thetaminradEditFieldLabel = uilabel(app.Panel_7);
            app.thetaminradEditFieldLabel.Text = 'theta min (rad)';
            app.thetaminradEditField = uieditfield(app.Panel_7, 'numeric');
            app.thetamaxradEditFieldLabel = uilabel(app.Panel_7);
            app.thetamaxradEditFieldLabel.Text = 'theta max (rad)';
            app.thetamaxradEditField = uieditfield(app.Panel_7, 'numeric');
            app.ScatteringIntegralsLabel = uilabel(app.Panel_7);
            app.ScatteringIntegralsLabel.Text = 'Scattering Integrals';
            app.ExecuteButton_3 = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.ExecuteButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_3Pushed, true);
            app.ExecuteButton_3.Text = 'Execute';
            app.TabGroup2_3 = uitabgroup(app.CalculateScatteringIntegralsTab);
            app.DifferentialScatteringCrossSectionTab = uitab(app.TabGroup2_3);
            app.DifferentialScatteringCrossSectionTab.Title = 'Differential Scattering Cross Section';
            app.UIAxes_5 = uiaxes(app.DifferentialScatteringCrossSectionTab);
            title(app.UIAxes_5, 'Differential Scattering Cross Section')
            xlabel(app.UIAxes_5, 'Scattering Angle (rad)')
            ylabel(app.UIAxes_5, 'Cross Section (Angstrom^2/sr)')
            zlabel(app.UIAxes_5, 'Z')
            app.ImpactParameterTab = uitab(app.TabGroup2_3);
            app.ImpactParameterTab.Title = 'Impact Parameter';
            app.UIAxes_6 = uiaxes(app.ImpactParameterTab);
            title(app.UIAxes_6, 'Impact Parameter')
            xlabel(app.UIAxes_6, 'Scattering Angle (rad)')
            ylabel(app.UIAxes_6, 'Impact Parameter (Angstrom)')
            zlabel(app.UIAxes_6, 'Z')
            app.DistofClosestApproachTab = uitab(app.TabGroup2_3);
            app.DistofClosestApproachTab.Title = 'Dist. of Closest Approach';
            app.UIAxes_7 = uiaxes(app.DistofClosestApproachTab);
            title(app.UIAxes_7, 'Distance of Closest Approach')
            xlabel(app.UIAxes_7, 'Impact Parameter (Angstrom)')
            ylabel(app.UIAxes_7, 'Distance (Angstrom)')
            zlabel(app.UIAxes_7, 'Z')
            app.SelectPathButton = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.SpecifyWheretoSaveDifferentialScatteringDataLabel = uilabel(app.CalculateScatteringIntegralsTab);
            app.SpecifyWheretoSaveDifferentialScatteringDataLabel.Text = 'Specify Where to Save Differential Scattering Data';
            app.SelectedPathEditField_5Label = uilabel(app.CalculateScatteringIntegralsTab);
            app.SelectedPathEditField_5Label.Text = 'Selected Path';
            app.SelectedPathEditField_5 = uieditfield(app.CalculateScatteringIntegralsTab, 'text');
            app.WriteScatteringInputFileButton = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.WriteScatteringInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteScatteringInputFileButtonPushed, true);
            app.WriteScatteringInputFileButton.Text = 'Write Scattering Input File';
            app.OrselectyourownscatteringinputfileLabel = uilabel(app.CalculateScatteringIntegralsTab);
            app.OrselectyourownscatteringinputfileLabel.Text = 'Or select your own scattering input file';
            app.SelectScatteringInputFileButton = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.SelectScatteringInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectScatteringInputFileButtonPushed, true);
            app.SelectScatteringInputFileButton.Text = 'Select Scattering Input File';
            app.SelectedFileEditFieldLabel = uilabel(app.CalculateScatteringIntegralsTab);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.CalculateScatteringIntegralsTab, 'text');
            app.SpecifyWheretoSaveInputFileLabel_2 = uilabel(app.CalculateScatteringIntegralsTab);
            app.SpecifyWheretoSaveInputFileLabel_2.Text = 'Specify Where to Save Input File';
            app.SelectedPathEditField_6Label = uilabel(app.CalculateScatteringIntegralsTab);
            app.SelectedPathEditField_6Label.Text = 'Selected Path';
            app.SelectedPathEditField_6 = uieditfield(app.CalculateScatteringIntegralsTab, 'text');
            app.SelectPathButton_2 = uibutton(app.CalculateScatteringIntegralsTab, 'push');
            app.SelectPathButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButton_2Pushed, true);
            app.SelectPathButton_2.Text = 'Select Path';
            app.CalcuateTransportCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateTransportCoefficientsTab.Title = 'Calcuate Transport Coefficients';
            app.CalcuateDSMCCoefficientsTab = uitab(app.TabGroup);
            app.CalcuateDSMCCoefficientsTab.Title = 'Calcuate DSMC Coefficients';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end